classdef Version2_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        ConectarBLEButton       matlab.ui.control.Button
        entradaYC               matlab.ui.control.NumericEditField
        EjeYEditField_4Label    matlab.ui.control.Label
        entradaXC               matlab.ui.control.NumericEditField
        EjeXEditField_4Label    matlab.ui.control.Label
        entradaYB               matlab.ui.control.NumericEditField
        EjeYEditField_3Label    matlab.ui.control.Label
        entradaXB               matlab.ui.control.NumericEditField
        EjeXEditField_3Label    matlab.ui.control.Label
        PuntoC                  matlab.ui.control.Label
        PuntoB                  matlab.ui.control.Label
        entradaAnguloL          matlab.ui.control.NumericEditField
        AnguloEditField_2Label  matlab.ui.control.Label
        EjeYEditField_2Label    matlab.ui.control.Label
        entradaYL               matlab.ui.control.NumericEditField
        EjeXEditField_2Label    matlab.ui.control.Label
        entradaXL               matlab.ui.control.NumericEditField
        AnguloEditFieldLabel    matlab.ui.control.Label
        Local                   matlab.ui.control.Label
        EjeYEditFieldLabel      matlab.ui.control.Label
        entradaAnguloG          matlab.ui.control.NumericEditField
        entradaYG               matlab.ui.control.NumericEditField
        entradaXG               matlab.ui.control.NumericEditField
        EjeXEditFieldLabel      matlab.ui.control.Label
        Global                  matlab.ui.control.Label
        ValoresaIngresar        matlab.ui.control.Label
        IngresarButton          matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        bt % Objeto Bluetooth
        timer % Objeto Timer
        posicionActual % [X Y Theta]
        ELocal % Valores del Espacio Local
        puntoB % Valores del Punto B
        puntoC % Valores del Punto C
        rxChar % Mensaje a enviar
        txChar % Mensaje a recibir
        msg % Último mensaje recibido vía BLE
    end

    methods (Access = private)

        % Funciones auxiliares
        function plotRobot(app)
            % Limpiar ejes
            cla(app.UIAxes)
            hold(app.UIAxes, 'on')

            % Dibujar robot
            plot(app.UIAxes, app.posicionActual(1), app.posicionActual(2), 'ko', 'MarkerSize', 10, 'LineWidth', 2)

            % Dibujar flecha de dirección
            longitud_flecha = 1; % Puedes ajustar la longitud
            u = longitud_flecha * cosd(app.posicionActual(3));
            v = longitud_flecha * sind(app.posicionActual(3));
            quiver(app.UIAxes, app.posicionActual(1), app.posicionActual(2), u, v, 0, 'k', 'LineWidth', 1.5, 'MaxHeadSize', 2)

            % Dibujar puntos B y C en global
            theta_rad = deg2rad(app.ELocal(3));
            R = [cos(theta_rad), -sin(theta_rad); sin(theta_rad), cos(theta_rad)];

            puntoB_global = R * app.puntoB' + [app.ELocal(1); app.ELocal(2)];
            puntoC_global = R * app.puntoC' + [app.ELocal(1); app.ELocal(2)];

            plot(app.UIAxes, puntoB_global(1), puntoB_global(2), 'r*', 'MarkerSize', 10)
            plot(app.UIAxes, puntoC_global(1), puntoC_global(2), 'b*', 'MarkerSize', 10)

            % Opcional: dibujar líneas entre puntos
            plot(app.UIAxes, [app.posicionActual(1), puntoB_global(1), puntoC_global(1)], [app.posicionActual(2), puntoB_global(2), puntoC_global(2)], 'k--')

            % Ajustar ejes
            axis(app.UIAxes, 'equal')
            xlim(app.UIAxes, [app.posicionActual(1)-5 app.posicionActual(1)+5])
            ylim(app.UIAxes, [app.posicionActual(2)-5 app.posicionActual(2)+5])
            grid(app.UIAxes, 'on')

            xlabel(app.UIAxes, 'Eje X')
            ylabel(app.UIAxes, 'Eje Y')
            title(app.UIAxes, 'Posición Robot en el sistema global')

            hold(app.UIAxes, 'off')
        end

        function actualizarRobot(app)
            try
                    readBLE(app)

                    % Convertir a tipo string
                    datos = app.msg; % Ejemplo "PX:12.345|PY:67.890|V:3.457|A:90.000|R:1500.123"

                    px = str2double(extractBetween(datos, "PX:", "|PY"));
                    py = str2double(extractBetween(datos, "PY:", "|V"));
                    v  = str2double(extractBetween(datos, "V:", "|A"));
                    a  = str2double(extractBetween(datos, "A:", "|R"));
                    r  = str2double(extractAfter(datos, "R:"));  % hasta el final
                    
                    format longG
                    disp([px, py, v, a, r]);

                    % Actualizar la posición actual
                    app.posicionActual = [px, py, a];

                    plotRobot(app)
            catch ME
                disp(['Error al actualizar: ' ME.message]);
            end
        end

        function enviarDatos(app, x, y, theta)
            app.msg = [ ...
                'GEX:', num2str(x, '%.1f'), '|', ...
                'GEY:', num2str(y, '%.1f'), '|', ...
                'GEA:', num2str(theta, '%.1f'), ';', ...
                'LEX:', num2str(app.ELocal(1), '%.1f'), '|', ...
                'LEY:', num2str(app.ELocal(2), '%.1f'), '|', ...
                'LEA:', num2str(app.ELocal(3), '%.1f'), ';', ...
                'BX:',  num2str(app.puntoB(1), '%.1f'), '|', ...
                'BY:',  num2str(app.puntoB(2), '%.1f'), ';', ...
                'CX:',  num2str(app.puntoC(1), '%.1f'), '|', ...
                'CY:',  num2str(app.puntoC(2), '%.1f'), ';' ...
                ];
            
        end

        function configBLE(app, deviceName, serviceUUID, txUUID, rxUUID)
            app.bt = ble(deviceName);
            app.txChar = characteristic(app.bt, serviceUUID, txUUID);  % Para leer notificaciones (ESP32 → MATLAB)
            app.rxChar = characteristic(app.bt, serviceUUID, rxUUID);  % Para escribir datos (MATLAB → ESP32)
        end

        function readBLE(app)
            app.msg = char(read(app.txChar));
        end

        function sendBLE(app)
            if ischar(app.msg)
                write(app.rxChar, uint8(app.msg));
                disp(app.msg)
            else
                error('El mensaje debe ser texto (char).');
            end
        end

        function closeBLE(app)
            % Cierra y limpia la conexión BLE si es válida
            if ~isempty(app.bt) && isvalid(app.bt)
                try
                    % Intenta borrar la conexión BLE
                    clear app.bt;
                    msgbox('Conexión BLE cerrada correctamente.', 'BLE Desconectado');
                catch e
                    errordlg(['Error al cerrar la conexión BLE: ', e.message], 'Error de Desconexión');
                end
            else
                warndlg('La conexión BLE no es válida o ya fue cerrada.', 'Advertencia');
            end

            % Limpia también las características BLE si existen
            if ~isempty(app.rxChar)
                app.rxChar = [];
            end

            if ~isempty(app.txChar)
                app.txChar = [];
            end

            disp('Propiedades rxChar, txChar y bt eliminadas (puestas en blanco).');
        end

    end

    methods (Access = public)

        function deleteTimer(app)
            if ~isempty(app.timer) && isvalid(app.timer)
                stop(app.timer);
                delete(app.timer);
            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IngresarButton
        function IngresarButtonPushed(app, event)
            % Leer datos iniciales
            x = app.entradaXG.Value;
            y = app.entradaYG.Value;
            theta = app.entradaAnguloG.Value;

            xL = app.entradaXL.Value;
            yL = app.entradaYL.Value;
            thetaL = app.entradaAnguloL.Value;

            app.posicionActual = [x, y, theta];
            app.ELocal = [xL, yL, thetaL];
            app.puntoB = [app.entradaXB.Value, app.entradaYB.Value];
            app.puntoC = [app.entradaXC.Value, app.entradaYC.Value];
            
            % Organisa datos a enviar al arduino
            enviarDatos(app, x, y, theta)

            % Envia datos
            sendBLE(app)

            % Graficar la posición inicial
            plotRobot(app)

            % Crear y arrancar timer para actualizar automáticamente
            if isempty(app.timer) || ~isvalid(app.timer)
                app.timer = timer('ExecutionMode', 'fixedRate', 'Period', 2, 'TimerFcn', @(~,~) actualizarRobot(app));
                start(app.timer)
            end
        end

        % Button pushed function: ConectarBLEButton
        function ConectarBLEButtonPushed(app, event)
            if isempty(app.bt) || ~isvalid(app.bt)
                % ➤ CONECTAR BLE
                try
                    deviceName = "ESP32_BLE";
                    serviceUUID = "12345678-1234-1234-1234-1234567890ab";
                    txUUID = "abcd1234-ab12-cd34-ef56-abcdef123456";
                    rxUUID = "dcba4321-ba21-dc43-fe65-fedcba654321";

                    % Llama a tu función configBLE
                    configBLE(app, deviceName, serviceUUID, txUUID, rxUUID);

                    % Cambia el texto del botón
                    app.ConectarBLEButton.Text = 'Desconectar BLE';
                    msgbox('Conexión BLE establecida.', 'Éxito');

                catch e
                    errordlg(['Error al conectar BLE: ' e.message], 'Fallo de conexión');
                end
            else
                % ➤ DESCONECTAR BLE
                closeBLE(app);
                app.ConectarBLEButton.Text = 'Conectar BLE';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Posición Robot en el sistema global')
            xlabel(app.UIAxes, 'Eje X')
            ylabel(app.UIAxes, 'Eje Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 10];
            app.UIAxes.YLim = [0 10];
            app.UIAxes.ZLim = [0 10];
            app.UIAxes.XTick = [0 1 2 3 4 5 6 7 8 9 10];
            app.UIAxes.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'};
            app.UIAxes.YTick = [0 1 2 3 4 5 6 7 8 9 10];
            app.UIAxes.YTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'};
            app.IngresarButton = uibutton(app.GridLayout, 'push');
            app.IngresarButton.ButtonPushedFcn = createCallbackFcn(app, @IngresarButtonPushed, true);
            app.IngresarButton.Text = 'Ingresar';
            app.ValoresaIngresar = uilabel(app.GridLayout);
            app.ValoresaIngresar.Text = 'Valores a Ingresar';
            app.Global = uilabel(app.GridLayout);
            app.Global.Text = 'Posición en el sistema global (m)';
            app.EjeXEditFieldLabel = uilabel(app.GridLayout);
            app.EjeXEditFieldLabel.Text = 'Eje X';
            app.entradaXG = uieditfield(app.GridLayout, 'numeric');
            app.entradaXG.Value = 10.5;
            app.entradaYG = uieditfield(app.GridLayout, 'numeric');
            app.entradaYG.Value = 20.2;
            app.entradaAnguloG = uieditfield(app.GridLayout, 'numeric');
            app.entradaAnguloG.Value = 30.3;
            app.EjeYEditFieldLabel = uilabel(app.GridLayout);
            app.EjeYEditFieldLabel.Text = 'Eje Y';
            app.Local = uilabel(app.GridLayout);
            app.Local.Text = 'Posición del sistema local';
            app.AnguloEditFieldLabel = uilabel(app.GridLayout);
            app.AnguloEditFieldLabel.Text = 'Angulo (°)';
            app.entradaXL = uieditfield(app.GridLayout, 'numeric');
            app.entradaXL.Value = 5;
            app.EjeXEditField_2Label = uilabel(app.GridLayout);
            app.EjeXEditField_2Label.Text = 'Eje X';
            app.entradaYL = uieditfield(app.GridLayout, 'numeric');
            app.entradaYL.Value = 15;
            app.EjeYEditField_2Label = uilabel(app.GridLayout);
            app.EjeYEditField_2Label.Text = 'Eje Y';
            app.AnguloEditField_2Label = uilabel(app.GridLayout);
            app.AnguloEditField_2Label.Text = 'Angulo (°)';
            app.entradaAnguloL = uieditfield(app.GridLayout, 'numeric');
            app.entradaAnguloL.Value = 25;
            app.PuntoB = uilabel(app.GridLayout);
            app.PuntoB.Text = 'Punto B';
            app.PuntoC = uilabel(app.GridLayout);
            app.PuntoC.Text = 'Punto C';
            app.EjeXEditField_3Label = uilabel(app.GridLayout);
            app.EjeXEditField_3Label.Text = 'Eje X';
            app.entradaXB = uieditfield(app.GridLayout, 'numeric');
            app.entradaXB.Value = 100;
            app.EjeYEditField_3Label = uilabel(app.GridLayout);
            app.EjeYEditField_3Label.Text = 'Eje Y';
            app.entradaYB = uieditfield(app.GridLayout, 'numeric');
            app.entradaYB.Value = 200;
            app.EjeXEditField_4Label = uilabel(app.GridLayout);
            app.EjeXEditField_4Label.Text = 'Eje X';
            app.entradaXC = uieditfield(app.GridLayout, 'numeric');
            app.entradaXC.Value = 300;
            app.EjeYEditField_4Label = uilabel(app.GridLayout);
            app.EjeYEditField_4Label.Text = 'Eje Y';
            app.entradaYC = uieditfield(app.GridLayout, 'numeric');
            app.entradaYC.Value = 400;
            app.ConectarBLEButton = uibutton(app.GridLayout, 'push');
            app.ConectarBLEButton.ButtonPushedFcn = createCallbackFcn(app, @ConectarBLEButtonPushed, true);
            app.ConectarBLEButton.Text = 'Conectar BLE';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Version2_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end